classdef OxygenSaturationCalculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        OxygenCalculatorUIFigure   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        Mua1EditField              matlab.ui.control.NumericEditField
        Muacm1Label                matlab.ui.control.Label
        Wavelength1EditField       matlab.ui.control.NumericEditField
        Label_2                    matlab.ui.control.Label
        Mua2EditField              matlab.ui.control.NumericEditField
        Wavelength2EditField       matlab.ui.control.NumericEditField
        OxygenSaturationEditField  matlab.ui.control.NumericEditField
        OxygenSaturationButton     matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        logTextArea                matlab.ui.control.TextArea
        logTextAreaLabel           matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            name = 'oxygen saturation calculator';
            app.OxygenCalculatorUIFigure.Name = name;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.OxygenCalculatorUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {315, 315};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {312, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: OxygenSaturationButton
        function OxygenSaturationButtonPushed(app, event)
                       
            % Calculate the oxygen saturation
            wav1 = app.Wavelength1EditField.Value;
            wav2 = app.Wavelength2EditField.Value;
            mua1 =  app.Mua1EditField.Value;
            mua2 =  app.Mua2EditField.Value;
%             wavList = {'802', '838'};%CHOOSE ANOTHER WAVELENGTH LIKE 802 838
            wavList = {num2str(wav1), num2str(wav2)};
            muaList = [mua1  mua2].*0.1; %mm-1
            [oxy, THb, C ] = get_oxy_tHb(muaList,  wavList);
            app.OxygenSaturationEditField.Value = oxy .* 100; %[%]
            
            % log [output the history entries]
            % time, wavs, muas, and oxy
            t = datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss');
            message = sprintf('oxygen saturation is: %5.1f %%',app.OxygenSaturationEditField.Value );
            message = ['Time: ' char(t) ', ' message];
            if strcmp(app.logTextArea.Value,'')
                  message_disp = message;
              else
                  message_disp = [app.logTextArea.Value(:)', {message}];
              end
              app.logTextArea.Value = message_disp;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.OxygenCalculatorUIFigure = uifigure('Visible', 'off');
            app.OxygenCalculatorUIFigure.Name = 'Mortgage Calculator';
            app.OxygenCalculatorUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.OxygenCalculatorUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.OxygenSaturationButton = uibutton(app.LeftPanel, 'push');
            app.OxygenSaturationButton.ButtonPushedFcn = createCallbackFcn(app, @OxygenSaturationButtonPushed, true);
            app.OxygenSaturationButton.Text = 'Oxygen Saturation [%]';
            app.OxygenSaturationEditField = uieditfield(app.LeftPanel, 'numeric');
            app.OxygenSaturationEditField.ValueDisplayFormat = '%5.1f';
            app.OxygenSaturationEditField.Editable = 'off';
            app.Wavelength2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Wavelength2EditField.Value = 838;
            app.Mua2EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Mua2EditField.Value = 0.151;
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Text = 'wavelength  [nm]';
            app.Wavelength1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Wavelength1EditField.Value = 802;
            app.Muacm1Label = uilabel(app.LeftPanel);
            app.Muacm1Label.Text = 'Mua [cm-1]';
            app.Mua1EditField = uieditfield(app.LeftPanel, 'numeric');
            app.Mua1EditField.Value = 0.13;
            app.RightPanel = uipanel(app.GridLayout);
            app.logTextAreaLabel = uilabel(app.RightPanel);
            app.logTextAreaLabel.Text = 'log';
            app.logTextArea = uitextarea(app.RightPanel);
            % Show the figure after all components are created
            app.OxygenCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OxygenSaturationCalculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.OxygenCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.OxygenCalculatorUIFigure)
        end
    end
end